classdef intersectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrafficIntersectionControlLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RegularControlTab               matlab.ui.container.Tab
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        DiagnosticTab                   matlab.ui.container.Tab
        StartButton_2                   matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        SpeedButtonGroup                matlab.ui.container.ButtonGroup
        speed_1x                        matlab.ui.control.RadioButton
        speed_3x                        matlab.ui.control.RadioButton
        speed_5x                        matlab.ui.control.RadioButton
        TrafficLightsSwitchLabel        matlab.ui.control.Label
        TrafficLightsSwitch             matlab.ui.control.RockerSwitch
        CrosswalksSwitchLabel           matlab.ui.control.Label
        CrosswalksSwitch                matlab.ui.control.RockerSwitch
        AmbientLightSwitchLabel         matlab.ui.control.Label
        AmbientLightSwitch              matlab.ui.control.RockerSwitch
        TurnLanesSwitchLabel            matlab.ui.control.Label
        TurnLanesSwitch                 matlab.ui.control.RockerSwitch
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.NumericEditField
        SensorsPanel                    matlab.ui.container.Panel
        CarinTLALampLabel               matlab.ui.control.Label
        CarinTLALamp                    matlab.ui.control.Lamp
        CarinTLCLampLabel               matlab.ui.control.Label
        CarinTLCLamp                    matlab.ui.control.Lamp
        NSCWPressLampLabel              matlab.ui.control.Label
        NSCWPressLamp                   matlab.ui.control.Lamp
        EWCWPressLampLabel              matlab.ui.control.Label
        EWCWPressLamp                   matlab.ui.control.Lamp
        LightIndicatorsPanel            matlab.ui.container.Panel
        LightALampLabel                 matlab.ui.control.Label
        LightALamp                      matlab.ui.control.Lamp
        LightBLampLabel                 matlab.ui.control.Label
        LightBLamp                      matlab.ui.control.Lamp
        LightCLampLabel                 matlab.ui.control.Label
        LightCLamp                      matlab.ui.control.Lamp
        LightDLampLabel                 matlab.ui.control.Label
        LightDLamp                      matlab.ui.control.Lamp
        LightELampLabel                 matlab.ui.control.Label
        LightELamp                      matlab.ui.control.Lamp
        NSCrosswalkLamp_2Label          matlab.ui.control.Label
        NSCrosswalkLamp_2               matlab.ui.control.Lamp
        EWCrosswalkLamp_2Label          matlab.ui.control.Label
        EWCrosswalkLamp_2               matlab.ui.control.Lamp
        LuxEditFieldLabel               matlab.ui.control.Label
        LuxEditField                    matlab.ui.control.NumericEditField
        CrosswalkActivationStatusPanel  matlab.ui.container.Panel
        NSCWActivatedLampLabel          matlab.ui.control.Label
        NSCWActivatedLamp               matlab.ui.control.Lamp
        EWCWActivatedLampLabel          matlab.ui.control.Label
        EWCWActivatedLamp               matlab.ui.control.Lamp
    end


    properties (Access = private)
        s % Speed
        stop % tells function to stop
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            clear;
            clc;
            initializeAll(app.s);
            while app.stop ~= 1
                trafficArray = trafficLights();
                carSense = turnLanes();
                cwStates = crossWalkLights();
                clickStates = crossWalkButtons();
                lux = ambientLight();
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            turnOffLights();
            app.stop = 1;
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            clear;
            clc;
            initializeAll(app.s);
            while app.stop ~= 1
                trafficArray = trafficLights();
                carSense = turnLanes();
                cwStates = crossWalkLights();
                clickStates = crossWalkButtons();
                lux = ambientLight();
            end
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            turnOffLights();
            app.stop = 1;
        end

        % Selection changed function: SpeedButtonGroup
        function SpeedButtonGroupSelectionChanged(app, event)
            selectedButton = app.SpeedButtonGroup.SelectedObject;
            switch selectedButton
                case app.speed_1x
                    app.s = 1;
                case app.speed_3x
                    app.s = 3;
                case app.speed_5x
                    app.s = 5;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TrafficIntersectionControlLabel = uilabel(app.UIFigure);
            app.TrafficIntersectionControlLabel.Text = 'Traffic Intersection Control';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegularControlTab = uitab(app.TabGroup);
            app.RegularControlTab.Title = 'Regular Control';
            app.StartButton = uibutton(app.RegularControlTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RegularControlTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DiagnosticTab = uitab(app.TabGroup);
            app.DiagnosticTab.Title = 'Diagnostic';
            app.StartButton_2 = uibutton(app.DiagnosticTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.DiagnosticTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.SpeedButtonGroup = uibuttongroup(app.DiagnosticTab);
            app.SpeedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpeedButtonGroupSelectionChanged, true);
            app.SpeedButtonGroup.Title = 'Speed';
            app.speed_1x = uiradiobutton(app.SpeedButtonGroup);
            app.speed_1x.Text = '1X';
            app.speed_1x.Value = true;
            app.speed_3x = uiradiobutton(app.SpeedButtonGroup);
            app.speed_3x.Text = '3X';
            app.speed_5x = uiradiobutton(app.SpeedButtonGroup);
            app.speed_5x.Text = '5X';
            app.TrafficLightsSwitchLabel = uilabel(app.DiagnosticTab);
            app.TrafficLightsSwitchLabel.Text = 'Traffic Lights';
            app.TrafficLightsSwitch = uiswitch(app.DiagnosticTab, 'rocker');
            app.CrosswalksSwitchLabel = uilabel(app.DiagnosticTab);
            app.CrosswalksSwitchLabel.Text = 'Crosswalks';
            app.CrosswalksSwitch = uiswitch(app.DiagnosticTab, 'rocker');
            app.AmbientLightSwitchLabel = uilabel(app.DiagnosticTab);
            app.AmbientLightSwitchLabel.Text = 'Ambient Light';
            app.AmbientLightSwitch = uiswitch(app.DiagnosticTab, 'rocker');
            app.TurnLanesSwitchLabel = uilabel(app.DiagnosticTab);
            app.TurnLanesSwitchLabel.Text = 'TurnLanes';
            app.TurnLanesSwitch = uiswitch(app.DiagnosticTab, 'rocker');
            app.TimeEditFieldLabel = uilabel(app.DiagnosticTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.DiagnosticTab, 'numeric');
            app.TimeEditField.RoundFractionalValues = 'on';
            app.TimeEditField.ValueDisplayFormat = '%.0f';
            app.TimeEditField.Editable = 'off';
            app.SensorsPanel = uipanel(app.DiagnosticTab);
            app.SensorsPanel.Title = 'Sensors';
            app.CarinTLALampLabel = uilabel(app.SensorsPanel);
            app.CarinTLALampLabel.Text = 'Car in TLA?';
            app.CarinTLALamp = uilamp(app.SensorsPanel);
            app.CarinTLCLampLabel = uilabel(app.SensorsPanel);
            app.CarinTLCLampLabel.Text = 'Car in TLC?';
            app.CarinTLCLamp = uilamp(app.SensorsPanel);
            app.NSCWPressLampLabel = uilabel(app.SensorsPanel);
            app.NSCWPressLampLabel.Text = 'NS CW Press?';
            app.NSCWPressLamp = uilamp(app.SensorsPanel);
            app.EWCWPressLampLabel = uilabel(app.SensorsPanel);
            app.EWCWPressLampLabel.Text = 'EW CW Press?';
            app.EWCWPressLamp = uilamp(app.SensorsPanel);
            app.LightIndicatorsPanel = uipanel(app.DiagnosticTab);
            app.LightIndicatorsPanel.Title = 'Light Indicators';
            app.LightALampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightALampLabel.Text = 'Light A';
            app.LightALamp = uilamp(app.LightIndicatorsPanel);
            app.LightBLampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightBLampLabel.Text = 'Light B';
            app.LightBLamp = uilamp(app.LightIndicatorsPanel);
            app.LightCLampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightCLampLabel.Text = 'Light C';
            app.LightCLamp = uilamp(app.LightIndicatorsPanel);
            app.LightDLampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightDLampLabel.Text = 'Light D';
            app.LightDLamp = uilamp(app.LightIndicatorsPanel);
            app.LightELampLabel = uilabel(app.LightIndicatorsPanel);
            app.LightELampLabel.Text = 'Light E';
            app.LightELamp = uilamp(app.LightIndicatorsPanel);
            app.NSCrosswalkLamp_2Label = uilabel(app.LightIndicatorsPanel);
            app.NSCrosswalkLamp_2Label.Text = 'NS Crosswalk';
            app.NSCrosswalkLamp_2 = uilamp(app.LightIndicatorsPanel);
            app.EWCrosswalkLamp_2Label = uilabel(app.LightIndicatorsPanel);
            app.EWCrosswalkLamp_2Label.Text = 'EW Crosswalk';
            app.EWCrosswalkLamp_2 = uilamp(app.LightIndicatorsPanel);
            app.LuxEditFieldLabel = uilabel(app.DiagnosticTab);
            app.LuxEditFieldLabel.Text = 'Lux';
            app.LuxEditField = uieditfield(app.DiagnosticTab, 'numeric');
            app.LuxEditField.Editable = 'off';
            app.CrosswalkActivationStatusPanel = uipanel(app.DiagnosticTab);
            app.CrosswalkActivationStatusPanel.Title = 'Crosswalk Activation Status';
            app.NSCWActivatedLampLabel = uilabel(app.CrosswalkActivationStatusPanel);
            app.NSCWActivatedLampLabel.Text = 'NS CW - Activated';
            app.NSCWActivatedLamp = uilamp(app.CrosswalkActivationStatusPanel);
            app.EWCWActivatedLampLabel = uilabel(app.CrosswalkActivationStatusPanel);
            app.EWCWActivatedLampLabel.Text = 'EW CW - Activated';
            app.EWCWActivatedLamp = uilamp(app.CrosswalkActivationStatusPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = intersectionGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end